classdef Random < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        DashBoardTab          matlab.ui.container.Tab
        Lamp                  matlab.ui.control.Lamp
        HTML                  matlab.ui.control.HTML
        T_avg_Pow_Display     matlab.ui.control.NumericEditField
        TavgpowLabel          matlab.ui.control.Label
        TimeACF_Dispaly       matlab.ui.control.NumericEditField
        TimeACFLabel          matlab.ui.control.Label
        TimeMean_Display      matlab.ui.control.NumericEditField
        TimeMeanLabel         matlab.ui.control.Label
        N_Input               matlab.ui.control.NumericEditField
        NLabel_2              matlab.ui.control.Label
        ACF_Display           matlab.ui.control.NumericEditField
        AutocorrLabel         matlab.ui.control.Label
        M_Input               matlab.ui.control.NumericEditField
        MLabel                matlab.ui.control.Label
        J_Input               matlab.ui.control.NumericEditField
        jLabel                matlab.ui.control.Label
        I_Input               matlab.ui.control.NumericEditField
        iLabel                matlab.ui.control.Label
        ClearButton           matlab.ui.control.Button
        RunButton             matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        FileLabel             matlab.ui.control.Label
        InputFileName         matlab.ui.control.EditField
        EnsumbleTab           matlab.ui.container.Tab
        EnsemblePlot          matlab.ui.control.UIAxes
        EnsembleMeanTab       matlab.ui.container.Tab
        ThreeRamdomSamplefunctionsLabel  matlab.ui.control.Label
        SampleFunction3plot   matlab.ui.control.UIAxes
        SampleFunction2plot   matlab.ui.control.UIAxes
        SampleFunction1plot   matlab.ui.control.UIAxes
        EnsambleMeanPlot      matlab.ui.control.UIAxes
        ACF3DTab              matlab.ui.container.Tab
        ACF3D                 matlab.ui.control.UIAxes
        TimeACFTab            matlab.ui.container.Tab
        TimeACF_Plot          matlab.ui.control.UIAxes
        PSDTab                matlab.ui.container.Tab
        PSD_plot              matlab.ui.control.UIAxes
        TotalAveragePowerTab  matlab.ui.container.Tab
        TAPDisplay            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fileName             % file name of the input random process;
        M                    % Number of sample functions to be plotted.
        N                    % index of sample function.
        ti                   % index of sample i;
        tj                   % index of sample j.
        time                 % time array;
        RandomProcess        % 2D array of the Random process.
        RPS                  % Random process size;
        Ensemble             % 2D array of the Ensemble.
        ES                   % Ensemble size.
        probabilities        % probabilities of every sample func.
        EnsembleMean         % Ensemble Mean.
        ACF                  % Auto corr func between ith and jth samples.
        All_ACF              % Auto corr func between all samples.
        TimeMean             % Time Mean of the nth sample func.
        TimeACF              % Time auto correlation function.
        all_TimeACF          % Vector of Time ACF for all possible Taus.
        PSD                  % Power spectral density.
        ALL_PSD              % PSD between all samples.
        Avg_power      % Total average power.
        TotalAveragePower         % Average power of the ensemble.
    end
    
    methods (Access = private)
        

%-----------------------> DISPLAY CHOOSE FILE DIALOG. <--------------------


        function getFile(app)
            [file,path] = uigetfile(".mat");
            app.fileName = string(path)+string(file);
        end
        

%==========================================================================

%-------------------> READ THE FILE ENTERED BY THE USER. <-----------------

        function readFile(app,file)

%           check if file exist or valid.
            if exist(file,"file")

%               store the file in a variable.
                Input = load(file);

%               convert from struct type to cell type to allow dynamic
%               acess.
                Input= struct2cell(Input);

%               get the Random process 2d array.
                app.RandomProcess = Input{1};
                app.time = Input{2};
                
%               get the time vector.
                app.Lamp.Color = "green";
            else
%               if file doesn't exist tell the user to enter valid file.
                app.Lamp.Color = "red"; v
                app.HTML.HTMLSource="<html><head><style>p {color:red;font-family:arial}</style></head><body><p>Enter a valid filename!</p></body></html>";
                app.InputFileName.FontColor = "red";
            end

        end
        
%==========================================================================

%-------------> CALCULATE PROBABILITIES OF SAMPLE FUNCTIONS. <-------------

        function getProbabilities(app)
%           check if random process matrix and time vecror is not empty.
            if(~isempty(app.RandomProcess) && ~isempty(app.time))
                
%               get the random process size.
                app.RPS = size(app.RandomProcess);

%               get the Ensemble of the random process.
                app.Ensemble = unique(app.RandomProcess,"rows");

%               get the size of the Ensemble.
                app.ES = size(app.Ensemble);

%               initiate an array to calculate number of repititions of
%               every sample function.
                C=zeros([app.ES(1)],1);
                for Ni=1:app.ES(1)
                    for Nj=1:app.RPS(1)
                        if(app.Ensemble(Ni,:) == app.RandomProcess(Nj,:))
                            C(Ni) = C(Ni)+1;
                        end
                    end
                end
                
%               devide by total number of sample func to get probabilities.
                C = C/app.RPS(1);

%               store the probability vector.
                app.probabilities = C;
            end
        end


%==========================================================================

%------------------------> PLOT M SAMPLE FUCTIONS  <-----------------------

        function plot_M_Sample_Functions(app)

%           check if Ensemble matrix and time vecror is not empty.
            if(~isempty(app.Ensemble) && ~isempty(app.time))

%               check if M is valid input.
                if(app.M > 0 && app.M <= app.ES(1))

%                   initiate temp sample function.
                    toPlot = zeros(app.M,[length(app.time)]);
                    
%                   plot every sample function up to M.
                    for k=1:app.M
                        toPlot(k,:) = app.Ensemble(k,:);
                        plot(app.EnsemblePlot,app.time,toPlot)
                    end 

%               if the user enters invalid M plot all Ensemble.
                elseif (app.M < 0 ||app.M > app.ES(1))
                    plot(app.EnsemblePlot,app.time,app.Ensemble);
                    app.Lamp.Color = "#20B2AA";
                    app.HTML.HTMLSource="<html><head><style>p {color:LightSeaGreen;font-family:arial}</style></head><body><p>Recieved Invalid Value for M. All Ensembl Plotted.</p></body></html>";
                    app.InputFileName.FontColor = "#20B2AA";

%               if the user enters invalid M plot all Ensemble.
                else
                    plot(app.EnsemblePlot,app.time,app.Ensemble);
                    app.Lamp.Color = "#20B2AA";
                    app.HTML.HTMLSource="<html><head><style>p {color:LightSeaGreen;font-family:arial}</style></head><body><p>Recieved no input for M. so, all ensemble plotted.</p></body></html>";
                    app.InputFileName.FontColor = "#20B2AA";
                end
            end
        end
        
%==========================================================================

%--------------------------> PLOT ENSEMBLE MEAN  <-------------------------

        function plotEnsembleMean(app)

%           check if Ensemble matrix and time vecror is not empty.
            if(~isempty(app.Ensemble) && ~isempty(app.time))

%               initiate a matrix to multiply Ensemble by probabilities.
                FRB_P = zeros(app.ES);
                for k=1:app.ES(1)
                    FRB_P(k,:) = app.Ensemble(k,:)*app.probabilities(k);
                end

%               get the average of the ensemble.
                app.EnsembleMean = sum(FRB_P);

%               plot the Ensemble mean.
                plot(app.EnsambleMeanPlot,app.time,app.EnsembleMean);

%              generate 3 random integers between 1 ans width of ensemble.
               if(height(app.Ensemble)>3)
                   rnd = randperm(app.ES(1),3);
    %              plot the three random sample functions.
                   plot(app.SampleFunction1plot,app.time,app.Ensemble(rnd(1),:))
                   plot(app.SampleFunction2plot,app.time,app.Ensemble(rnd(2),:))
                   plot(app.SampleFunction3plot,app.time,app.Ensemble(rnd(3),:))
               elseif(height(app.Ensemble)==2)
                   rnd = randperm(app.ES(1),2);
    %              plot the three random sample functions.
                   plot(app.SampleFunction1plot,app.time,app.Ensemble(rnd(1),:))
                   plot(app.SampleFunction2plot,app.time,app.Ensemble(rnd(2),:))
               else
                   plot(app.SampleFunction1plot,app.time,app.Ensemble(1,:))                   
               end
            end
        end
        

%==========================================================================

%-------------------------> GET AUTO CORREL FUNC. <------------------------

        function getACF(app)

%           check if Ensemble matrix and time vecror is not empty.
            if(~isempty(app.Ensemble) && ~isempty(app.time))
%               check if tau is valid.
                if(app.ti > 0 && app.tj > 0 && app.ti <= length(app.time) && app.tj <= length(app.time))

%                   get sample at ti.
                    Si = app.Ensemble(:,app.ti);

%                   get sample at tj.
                    Sj = app.Ensemble(:,app.tj);
                    
%                   calc ACF.
                    SiSj = Si.*Sj;
                    SiSjP = SiSj.*app.probabilities;
                    tempACF = sum(SiSjP);
                    app.ACF = tempACF;

%                   display ACF value.
                    app.ACF_Display.Value = app.ACF;
                    app.ACF_Display.FontColor = [0 0 0];
                end
            end
        end


%==========================================================================

%-------------------------> GET AUTO CORREL FUNC. <------------------------

        function plotACF3D(app)

%           check if Ensemble matrix and time vecror is not empty.
            if(~isempty(app.Ensemble) && ~isempty(app.time))

%               calc ACF.
                temp_ACF = zeros(app.ES(2));
                for k=1:app.ES(2)
                    for l=1:app.ES(2)
                        Si = app.Ensemble(:,k);
                        Sj = app.Ensemble(:,l);
                        SiSj = Si.*Sj;
                        SiSjP=SiSj.*app.probabilities;
                        temp_ACF(k,l) = sum(SiSjP);

                    end
                end
                app.All_ACF = temp_ACF;

%               x-axis linespace.
                x = 1:1:width(app.time);

%               y-axis linespace.
                y = 1:1:width(app.time);

%               3D plot of acf using mesh.
                mesh(app.ACF3D,x,y,app.All_ACF,XData=x,YData=y);
                colorbar(app.ACF3D,"east");
                xlabel(app.ACF3D,"i","Color","red",FontSize=25,FontWeight="bold")
                ylabel(app.ACF3D,"j","Color","red",FontSize=25,FontWeight="bold")

            end
        end


%==========================================================================

%----------------------------> GET TIME MEAN. <----------------------------

        function getTimeMean(app)

%           check if Ensemble matrix and time vecror is not empty.          
            if(~isempty(app.Ensemble) && ~isempty(app.time))

%               Check if N is valid.
                if(app.N > 0 && app.N < length(app.time))
                    SampleFunc_N = app.Ensemble(app.N,:);

%                   integrate with respect to time.                   
                    integ = trapz(app.time,SampleFunc_N);
                    
                    app.TimeMean = integ/(app.time(app.ES(2))-app.time(1)); 
                    app.TimeMean_Display.Value = app.TimeMean;
                    app.TimeMean_Display.FontColor = "black";
                else
                    %
                end
            end
        end

       
%==========================================================================

%-----------------------> GET TIME AUTO CORREL FUNC. <---------------------        
        
        function getTimeACF(app)

%           check if Ensemble matrix and time vecror is not empty.                     
            if(~isempty(app.Ensemble) && ~isempty(app.time))

%               Check if N is valid.
                if(app.N > 0 && app.N < length(app.time))
                    if(abs(app.tj - app.ti)>=0 &&(app.tj - app.ti)<width(app.time))
                        SampleFunc_N = app.Ensemble(app.N,:);
                        Tau = abs(app.tj - app.ti) ;
                        SFS = width(SampleFunc_N)-Tau;
                        d = zeros(1,SFS);
                            for j=1:SFS
                                d(j) = SampleFunc_N(j)*SampleFunc_N(j+Tau);
                            end
                            Ti = app.time(1);
                            Tf=app.time(width(app.time));
                            d_time = app.time(1:end-Tau);
                        if(width(d)>1)   
                            app.TimeACF = trapz(d_time,d)/(Tf-Ti);
                        else
                            app.TimeACF = (d*Ti)/(Tf-Ti);
                        end
                        app.TimeACF_Dispaly.Value = app.TimeACF;
                        app.TimeACF_Dispaly.FontColor = "black";
                    end
                end
            end
        end


%==========================================================================

%---------------------> PLOT ALL TIME AUTO CORREL FUNC. <------------------  

        function plotAll_TimeACF(app)
            if(~isempty(app.Ensemble) && ~isempty(app.time))
                if(app.N > 0 && app.N < length(app.time))
                    TauMax = width(app.time) - 1;
                    SampleFunctionA = app.Ensemble(app.N,:);
                    
                    TACF = zeros(1,TauMax);
                    for Tau=1:TauMax
                        size = width(SampleFunctionA) - Tau;
                            TACFA = zeros(1,size);
                        for i=1:size
                            TACFA(i) = SampleFunctionA(i)*SampleFunctionA(i+Tau);
                        end
                        Tf = app.time(width(app.time));
                        Ti = app.time(1);
                        d_time = app.time(app.time <= app.time(width(app.time)-Tau));
                        if(width(TACFA)>1)
                             integrated = trapz(d_time,TACFA)/(Tf-Ti);
                             TACF(Tau) = integrated;
                        else
                            TACF(Tau) = (TACFA*Ti)/(Tf-Ti);
                        end
                    end
                    app.all_TimeACF = TACF;
                    plot(app.TimeACF_Plot,app.all_TimeACF);
                end
            end
        end

%==========================================================================

%-----------------------> GET POWER SPECTRAL DENSITY <---------------------        

        function plotPSD(app)
            fftEnsemble = zeros(app.ES);
            avg = zeros(app.ES);
            for k=1:height(app.Ensemble)
                fftEnsemble(k,:) =abs(fftshift(fft(app.Ensemble(k,:))));
            end
            
            fftEnsemble = fftEnsemble.^2;
            for i=1:height(app.Ensemble)
              avg(i,:) = fftEnsemble(i,:).*app.probabilities(i);  
            end
            meanfft = sum(avg);
            Tf = max(app.time);
            Ti = min(app.time);
            psd = meanfft./(Tf - Ti);
            disp(psd);
            plot(app.PSD_plot,psd);
        end
        

%==========================================================================

%------------------------> GET TOTAL AVERAGE POWER <-----------------------

        function getTotalAvgPower(app)
            TAVP = zeros(1,width(app.Ensemble));
            for i=1:width(TAVP)
                TAVP(i) = bandpower(app.Ensemble(:,i));
            end
            app.Avg_power = TAVP;
            app.TotalAveragePower = sum(TAVP);
            plot(app.TAPDisplay,app.time,TAVP);
            app.T_avg_Pow_Display.Value = app.TotalAveragePower;
            app.T_avg_Pow_Display.FontColor = "black";
        end

%==========================================================================

%-----------------------> SET INITIAL STATES <----------------------------- 

        function setInitialStates(app)
            app.fileName = '';
            app.M = 0;
            app.N = 0;
            app.ti = 0;
            app.tj = 0;
            app.time = [];
            app.RandomProcess = [];
            app.RPS = [];
            app.Ensemble = [];
            app.ES = [];
            app.probabilities = [];
            app.EnsembleMean = [];
            app.ACF = [];
            app.All_ACF = [];
            app.TimeMean = [];
            app.PSD = [];
            app.ALL_PSD = [];
            app.ACF_Display.Value = 0;
            app.ACF_Display.FontColor = "white";
            app.TimeMean_Display.Value = 0;
            app.TimeMean_Display.FontColor = "white";
            app.TimeACF_Dispaly.Value = 0;
            app.TimeACF_Dispaly.FontColor = "white";
            app.T_avg_Pow_Display.Value = 0;
            app.T_avg_Pow_Display.FontColor = "white";
            app.InputFileName.FontColor = "black";
            app.Lamp.Color = [0.8 0.8 0.8];
            app.HTML.HTMLSource = "<p></p>";
            cla(app.EnsemblePlot);
            cla(app.EnsambleMeanPlot);
            cla(app.SampleFunction1plot);
            cla(app.SampleFunction2plot);
            cla(app.SampleFunction3plot);
            cla(app.ACF3D);
            cla(app.TimeACF_Plot);
            cla(app.PSD_plot);
            cla(app.TAPDisplay);
        end
             

%==========================================================================

%---------------------------------> CLEAR <--------------------------------  

        function clear(app)
            app.InputFileName.Value = '';
            app.fileName = '';
            app.M_Input.Value = 0;
            app.N_Input.Value = 0;
            app.I_Input.Value = 0;
            app.J_Input.Value = 0;
            app.M = 0;
            app.N = 0;
            app.ti = 0;
            app.tj = 0;
            app.time = [];
            app.RandomProcess = [];
            app.RPS = [];
            app.Ensemble = [];
            app.ES = [];
            app.probabilities = [];
            app.EnsembleMean = [];
            app.ACF = [];
            app.All_ACF = [];
            app.TimeMean = [];
            app.PSD = [];
            app.ALL_PSD = [];
            app.ACF_Display.Value = 0;
            app.ACF_Display.FontColor = "white";
            app.TimeMean_Display.Value = 0;
            app.TimeMean_Display.FontColor = "white";
            app.TimeACF_Dispaly.Value = 0;
            app.TimeACF_Dispaly.FontColor = "white";
            app.T_avg_Pow_Display.Value = 0;
            app.T_avg_Pow_Display.FontColor = "white";
            app.InputFileName.FontColor = "black";
            app.Lamp.Color = [0.8 0.8 0.8];
            app.HTML.HTMLSource = "<p></p>";
            cla(app.EnsemblePlot);
            cla(app.EnsambleMeanPlot);
            cla(app.SampleFunction1plot);
            cla(app.SampleFunction2plot);
            cla(app.SampleFunction3plot);
            cla(app.ACF3D);
            cla(app.TimeACF_Plot);
            cla(app.PSD_plot);
            cla(app.TAPDisplay);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.getFile();
            app.InputFileName.Value = app.fileName;
            app.InputFileName.FontColor = [0 0 0];
            app.InputFileName.FontSize = 18;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.setInitialStates();
            app.M = app.M_Input.Value;
            app.N = app.N_Input.Value;
            app.ti = app.I_Input.Value;
            app.tj = app.J_Input.Value;
            app.readFile(app.InputFileName.Value);
            app.getProbabilities();
            app.plot_M_Sample_Functions();
            app.plotEnsembleMean();
            app.plotACF3D();
            app.getTimeMean();
            app.getACF();
            app.getTimeACF();
            app.plotAll_TimeACF();
            app.plotPSD();
            app.getTotalAvgPower();
        end

        % Value changing function: InputFileName
        function InputFileNameValueChanging(app, event)
            app.InputFileName.FontColor = "black";
            app.HTML.HTMLSource = "<p></p>";
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.clear();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DashBoardTab = uitab(app.TabGroup);
            app.DashBoardTab.Title = 'Dash Board';
            app.InputFileName = uieditfield(app.DashBoardTab, 'text');
            app.InputFileName.ValueChangingFcn = createCallbackFcn(app, @InputFileNameValueChanging, true);
            app.FileLabel = uilabel(app.DashBoardTab);
            app.FileLabel.Text = 'File';
            app.Image = uiimage(app.DashBoardTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'add-document-33-807322.png');
            app.RunButton = uibutton(app.DashBoardTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ClearButton = uibutton(app.DashBoardTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.iLabel = uilabel(app.DashBoardTab);
            app.iLabel.Text = 'i';
            app.I_Input = uieditfield(app.DashBoardTab, 'numeric');
            app.jLabel = uilabel(app.DashBoardTab);
            app.jLabel.Text = 'j';
            app.J_Input = uieditfield(app.DashBoardTab, 'numeric');
            app.MLabel = uilabel(app.DashBoardTab);
            app.MLabel.Text = 'M';
            app.M_Input = uieditfield(app.DashBoardTab, 'numeric');
            app.AutocorrLabel = uilabel(app.DashBoardTab);
            app.AutocorrLabel.Text = 'Auto corr';
            app.ACF_Display = uieditfield(app.DashBoardTab, 'numeric');
            app.NLabel_2 = uilabel(app.DashBoardTab);
            app.NLabel_2.Text = 'N';
            app.N_Input = uieditfield(app.DashBoardTab, 'numeric');
            app.TimeMeanLabel = uilabel(app.DashBoardTab);
            app.TimeMeanLabel.Text = 'Time Mean';
            app.TimeMean_Display = uieditfield(app.DashBoardTab, 'numeric');
            app.TimeACFLabel = uilabel(app.DashBoardTab);
            app.TimeACFLabel.Text = 'Time ACF';
            app.TimeACF_Dispaly = uieditfield(app.DashBoardTab, 'numeric');
            app.TavgpowLabel = uilabel(app.DashBoardTab);
            app.TavgpowLabel.Text = 'T. avg. pow';
            app.T_avg_Pow_Display = uieditfield(app.DashBoardTab, 'numeric');
            app.HTML = uihtml(app.DashBoardTab);
            app.HTML.HTMLSource = '<p></p>';
            app.Lamp = uilamp(app.DashBoardTab);
            app.EnsumbleTab = uitab(app.TabGroup);
            app.EnsumbleTab.Title = 'Ensumble';
            app.EnsemblePlot = uiaxes(app.EnsumbleTab);
            title(app.EnsemblePlot, 'Ensemble')
            xlabel(app.EnsemblePlot, 'time')
            ylabel(app.EnsemblePlot, 'sample functions')
            zlabel(app.EnsemblePlot, 'Z')
            app.EnsembleMeanTab = uitab(app.TabGroup);
            app.EnsembleMeanTab.Title = 'Ensemble Mean';
            app.EnsambleMeanPlot = uiaxes(app.EnsembleMeanTab);
            title(app.EnsambleMeanPlot, 'Ensemble Mean')
            xlabel(app.EnsambleMeanPlot, 'time')
            ylabel(app.EnsambleMeanPlot, 'Mean')
            zlabel(app.EnsambleMeanPlot, 'Z')
            app.SampleFunction1plot = uiaxes(app.EnsembleMeanTab);
            title(app.SampleFunction1plot, 'Sample function')
            zlabel(app.SampleFunction1plot, 'Z')
            app.SampleFunction2plot = uiaxes(app.EnsembleMeanTab);
            title(app.SampleFunction2plot, 'Sample function')
            zlabel(app.SampleFunction2plot, 'Z')
            app.SampleFunction3plot = uiaxes(app.EnsembleMeanTab);
            title(app.SampleFunction3plot, 'Sample function')
            zlabel(app.SampleFunction3plot, 'Z')
            app.ThreeRamdomSamplefunctionsLabel = uilabel(app.EnsembleMeanTab);
            app.ThreeRamdomSamplefunctionsLabel.Text = 'Three Ramdom Sample functions';
            app.ACF3DTab = uitab(app.TabGroup);
            app.ACF3DTab.Title = 'ACF 3D ';
            app.ACF3D = uiaxes(app.ACF3DTab);
            title(app.ACF3D, 'ACF')
            xlabel(app.ACF3D, 'X')
            ylabel(app.ACF3D, 'ACF')
            zlabel(app.ACF3D, 'Z')
            app.TimeACFTab = uitab(app.TabGroup);
            app.TimeACFTab.Title = 'Time ACF';
            app.TimeACF_Plot = uiaxes(app.TimeACFTab);
            title(app.TimeACF_Plot, 'Time ACF')
            xlabel(app.TimeACF_Plot, 'time')
            ylabel(app.TimeACF_Plot, 'Time ACF')
            zlabel(app.TimeACF_Plot, 'Z')
            app.PSDTab = uitab(app.TabGroup);
            app.PSDTab.Title = 'PSD';
            app.PSD_plot = uiaxes(app.PSDTab);
            title(app.PSD_plot, 'Power Spectral Density')
            xlabel(app.PSD_plot, 'X')
            ylabel(app.PSD_plot, 'PSD')
            zlabel(app.PSD_plot, 'Z')
            app.TotalAveragePowerTab = uitab(app.TabGroup);
            app.TotalAveragePowerTab.Title = 'Total Average Power';
            app.TAPDisplay = uiaxes(app.TotalAveragePowerTab);
            title(app.TAPDisplay, 'Average Power')
            xlabel(app.TAPDisplay, 'time')
            ylabel(app.TAPDisplay, 'Averge Power')
            zlabel(app.TAPDisplay, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Random

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end